classdef Menu_editor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        File                          matlab.ui.container.Menu
        NewMenu                       matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        PrintMenu                     matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        About                         matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        ChooseamplitudeSlider         matlab.ui.control.Slider
        ChooseamplitudeSliderLabel    matlab.ui.control.Label
        ChoosefunctiontodrawDropDown  matlab.ui.control.DropDown
        ChoosefunctiontodrawDropDownLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Draw sin function
            t= -4*pi: 0.1: 4*pi;
            plot(app.UIAxes, app.ChooseamplitudeSlider.Value*sin(t));
        end

        % Value changed function: ChoosefunctiontodrawDropDown
        function ChoosefunctiontodrawDropDownValueChanged(app, event)
            value = app.ChoosefunctiontodrawDropDown.Value;
            t= -4*pi: 0.1: 4*pi;
            switch value
                case 1
                    % Draw sin function
                    plot(app.UIAxes, app.ChooseamplitudeSlider.Value*sin(t));
                case 2
                    % Draw cos function
                    plot(app.UIAxes, app.ChooseamplitudeSlider.Value*cos(t));
                case 3
                    % Draw sin function
                    plot(app.UIAxes, app.ChooseamplitudeSlider.Value*tanh(t));
                case 4
                    % Draw sin function
                    plot(app.UIAxes, app.ChooseamplitudeSlider.Value*coth(t));
            end
        end

        % Value changed function: ChooseamplitudeSlider
        function ChooseamplitudeSliderValueChanged(app, event)
            ChoosefunctiontodrawDropDownValueChanged(app, event);
            
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            choice= questdlg('Are you sure to quit? ',...
                'Choice menu', ...
                'Yes', 'No', 'No');
            switch choice
                case 'Yes'
                    close(app);
                case 'No'
                    % Do nothing
            end
        end

        % Menu selected function: About
        function AboutMenuSelected(app, event)
            web trigonometric_function_about.htm;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.NewMenu = uimenu(app.File);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.File);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.File);
            app.SaveMenu.Text = 'Save';
            app.PrintMenu = uimenu(app.File);
            app.PrintMenu.Text = 'Print';
            app.ExitMenu = uimenu(app.File);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.About = uimenu(app.UIFigure);
            app.About.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.About.Text = 'About';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trigonometric function graphic')
            xlabel(app.UIAxes, 't= [-4*pi : 4*pi]')
            ylabel(app.UIAxes, 'x')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.ChoosefunctiontodrawDropDownLabel = uilabel(app.UIFigure);
            app.ChoosefunctiontodrawDropDownLabel.Tag = 'dropdown1';
            app.ChoosefunctiontodrawDropDownLabel.Text = 'Choose function to draw';
            app.ChoosefunctiontodrawDropDown = uidropdown(app.UIFigure);
            app.ChoosefunctiontodrawDropDown.Items = {'Sin', 'Cos', 'Tanh', 'Coth'};
            app.ChoosefunctiontodrawDropDown.ItemsData = [1 2 3 4];
            app.ChoosefunctiontodrawDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosefunctiontodrawDropDownValueChanged, true);
            app.ChoosefunctiontodrawDropDown.Placeholder = 'Click here';
            app.ChoosefunctiontodrawDropDown.Value = 1;
            app.ChooseamplitudeSliderLabel = uilabel(app.UIFigure);
            app.ChooseamplitudeSliderLabel.Text = 'Choose amplitude';
            app.ChooseamplitudeSlider = uislider(app.UIFigure);
            app.ChooseamplitudeSlider.Limits = [1 5];
            app.ChooseamplitudeSlider.MajorTicks = [1 2 3 4 5];
            app.ChooseamplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @ChooseamplitudeSliderValueChanged, true);
            app.ChooseamplitudeSlider.Value = 1;
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Menu_editor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end