classdef calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        SHIFTOFFLabel     matlab.ui.control.Label
        CASIOFX6969Label  matlab.ui.control.Label
        TextArea          matlab.ui.control.TextArea
        TextAreaLabel     matlab.ui.control.Label
        Button_20         matlab.ui.control.Button
        Button_23         matlab.ui.control.Button
        Button_22         matlab.ui.control.Button
        Button_21         matlab.ui.control.Button
        Button_19         matlab.ui.control.Button
        Button_18         matlab.ui.control.Button
        Button_17         matlab.ui.control.Button
        Button_16         matlab.ui.control.Button
        Button_9          matlab.ui.control.Button
        Button_15         matlab.ui.control.Button
        Button_14         matlab.ui.control.Button
        Button_13         matlab.ui.control.Button
        Button_8          matlab.ui.control.Button
        Button_12         matlab.ui.control.Button
        Button_11         matlab.ui.control.Button
        Button_10         matlab.ui.control.Button
        CButton           matlab.ui.control.Button
        Button_7          matlab.ui.control.Button
        Button_6          matlab.ui.control.Button
        Button_5          matlab.ui.control.Button
        cosacosButton     matlab.ui.control.Button
        sinasinButton     matlab.ui.control.Button
        loglog10Button    matlab.ui.control.Button
        tanatanButton     matlab.ui.control.Button
        ShiftButton       matlab.ui.control.Button
        BackButton        matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function results = func(app)
        
        end
    end
    
    methods (Access = private)
        
        function results = func2(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global jj shift ;
            jj=0;
            shift =0;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) && jj==0
                app.TextArea.Value= {'1'};
            else
                textStr= strcat(textStr, '1');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) && jj==0
                app.TextArea.Value= {'2'};
            else
                textStr= strcat(textStr, '2');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) && jj==0
                app.TextArea.Value= {'3'};
            else
                textStr= strcat(textStr, '3');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) && jj==0
                app.TextArea.Value= {'4'};
            else
                textStr= strcat(textStr, '4');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) && jj==0
                app.TextArea.Value= {'5'};
            else
                textStr= strcat(textStr, '5');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) && jj==0
                app.TextArea.Value= {'6'};
            else
                textStr= strcat(textStr, '6');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) && jj==0
                app.TextArea.Value= {'7'};
            else
                textStr= strcat(textStr, '7');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) && jj==0
                app.TextArea.Value= {'8'};
            else
                textStr= strcat(textStr, '8');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) && jj==0
                app.TextArea.Value= {'9'};
            else
                textStr= strcat(textStr, '9');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) && jj==0
                app.TextArea.Value= {'0'};
            else
                textStr= strcat(textStr, '0');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            jj=1;
            if (strcmp(textStr, '0.')== 1) 
                pp.TextArea.Value= {'0.'};
            else
                textStr= strcat(textStr, '.');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            global jj shift
            jj=0;
            shift=0;
            app.TextArea.Value= {'0.'};
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            % Only using for first number. 
            textStr = app.TextArea.Value{1};
            num= strread(textStr, '%f');
            num= num*(-1);
            app.TextArea.Value={num2str(num)};

        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1)
                app.TextArea.Value= {'('};
            else
                textStr= strcat(textStr, '(');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) 
                %pp.TextArea.Value= {'('};
                % Do nothing
            else
                textStr= strcat(textStr, ')');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) && jj==0
                app.TextArea.Value= {'0.'};
                % Do nothing
            else
                textStr= strcat(textStr, '+');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) && jj==0
                app.TextArea.Value= {'0.'};
                % Do nothing
            else
                textStr= strcat(textStr, '-');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) && jj==0
                app.TextArea.Value= {'0.'};
                % Do nothing
            else
                textStr= strcat(textStr, '*');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) && jj==0
                app.TextArea.Value= {'0.'};
               
            else
                textStr= strcat(textStr, '/');
                app.TextArea.Value= {textStr};
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            global jj
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) && jj==1
                app.TextArea.Value= {'0.'};
                jj=0;
               
            else
                textLen= strlength(textStr);
                if textLen==1
                    app.TextArea.Value={'0.'};
                    jj=0;
                else
                    textStr= textStr(1:textLen-1);
                    app.TextArea.Value= {textStr};
                end
            end
        end

        % Button pushed function: ShiftButton
        function ShiftButtonPushed(app, event)
            global shift 
            if shift==0 
                shift =1;
                app.SHIFTOFFLabel.Text= "SHIFT ON";
            else
                shift =0;
                app.SHIFTOFFLabel.Text= "SHIFT OFF";
            end
            
        end

        % Button pushed function: sinasinButton
        function sinasinButtonPushed(app, event)
            global shift
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) 
                if shift==0
                    app.TextArea.Value={'sin'};
                else app.TextArea.Value= {'asin'};
                end
            else
                if shift ==0 
                    textStr= strcat(textStr, 'sin');
                    app.TextArea.Value= {textStr};
                else
                    textStr= strcat(textStr, 'asin');
                    app.TextArea.Value= {textStr};
                end
            end

        end

        % Button pushed function: cosacosButton
        function cosacosButtonPushed(app, event)
            global shift
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) 
                if shift==0
                    app.TextArea.Value={'cos'};
                else app.TextArea.Value= {'acos'};
                end
            else
                if shift ==0 
                    textStr= strcat(textStr, 'cos');
                    app.TextArea.Value= {textStr};
                else
                    textStr= strcat(textStr, 'acos');
                    app.TextArea.Value= {textStr};
                end
            end
        end

        % Button pushed function: tanatanButton
        function tanatanButtonPushed(app, event)
            global shift
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) 
                if shift==0
                    app.TextArea.Value={'tan'};
                else app.TextArea.Value= {'atan'};
                end
            else
                if shift ==0 
                    textStr= strcat(textStr, 'tan');
                    app.TextArea.Value= {textStr};
                else
                    textStr= strcat(textStr, 'atan');
                    app.TextArea.Value= {textStr};
                end
            end
        end

        % Button pushed function: loglog10Button
        function loglog10ButtonPushed(app, event)
            global shift
            textStr= app.TextArea.Value{1};
            if (strcmp(textStr, '0.')== 1) 
                if shift==0
                    app.TextArea.Value={'log'};
                else app.TextArea.Value= {'log10'};
                end
            else
                if shift ==0 
                    textStr= strcat(textStr, 'log');
                    app.TextArea.Value= {textStr};
                else
                    textStr= strcat(textStr, 'log10');
                    app.TextArea.Value= {textStr};
                end
            end
        end

        % Button pushed function: Button_23
        function Button_23Pushed(app, event)
            textStr= app.TextArea.Value{1};
            ans= eval(textStr);
            app.TextArea.Value= {num2str(ans)};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BackButton = uibutton(app.GridLayout, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ShiftButton = uibutton(app.GridLayout, 'push');
            app.ShiftButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftButtonPushed, true);
            app.ShiftButton.Text = 'Shift';
            app.tanatanButton = uibutton(app.GridLayout, 'push');
            app.tanatanButton.ButtonPushedFcn = createCallbackFcn(app, @tanatanButtonPushed, true);
            app.tanatanButton.Text = 'tan(atan)';
            app.loglog10Button = uibutton(app.GridLayout, 'push');
            app.loglog10Button.ButtonPushedFcn = createCallbackFcn(app, @loglog10ButtonPushed, true);
            app.loglog10Button.Text = 'log(log10)';
            app.sinasinButton = uibutton(app.GridLayout, 'push');
            app.sinasinButton.ButtonPushedFcn = createCallbackFcn(app, @sinasinButtonPushed, true);
            app.sinasinButton.Text = 'sin(asin)';
            app.cosacosButton = uibutton(app.GridLayout, 'push');
            app.cosacosButton.ButtonPushedFcn = createCallbackFcn(app, @cosacosButtonPushed, true);
            app.cosacosButton.Text = 'cos(acos)';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '1';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '2';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '3';
            app.CButton = uibutton(app.GridLayout, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.Button_10 = uibutton(app.GridLayout, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '4';
            app.Button_11 = uibutton(app.GridLayout, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '5';
            app.Button_12 = uibutton(app.GridLayout, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '6';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '+/-';
            app.Button_13 = uibutton(app.GridLayout, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '7';
            app.Button_14 = uibutton(app.GridLayout, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '8';
            app.Button_15 = uibutton(app.GridLayout, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '9';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '.';
            app.Button_16 = uibutton(app.GridLayout, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '+';
            app.Button_17 = uibutton(app.GridLayout, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '-';
            app.Button_18 = uibutton(app.GridLayout, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '0';
            app.Button_19 = uibutton(app.GridLayout, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '(';
            app.Button_21 = uibutton(app.GridLayout, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '*';
            app.Button_22 = uibutton(app.GridLayout, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '/';
            app.Button_23 = uibutton(app.GridLayout, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Button_23Pushed, true);
            app.Button_23.Text = '=';
            app.Button_20 = uibutton(app.GridLayout, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = ')';
            app.TextAreaLabel = uilabel(app.GridLayout);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Value = {'0.'};
            app.CASIOFX6969Label = uilabel(app.GridLayout);
            app.CASIOFX6969Label.Text = 'CASIO FX-6969';
            app.SHIFTOFFLabel = uilabel(app.GridLayout);
            app.SHIFTOFFLabel.Text = 'SHIFT OFF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end