classdef trigonometric_function < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label                         matlab.ui.control.Label
        ALabel                        matlab.ui.control.Label
        ChooseamplitudeSlider         matlab.ui.control.Slider
        ChooseamplitudeSliderLabel    matlab.ui.control.Label
        ChoosefunctiontodrawDropDown  matlab.ui.control.DropDown
        ChoosefunctiontodrawDropDownLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Draw sin function
            t= -10:0.1:10;
            plot(app.UIAxes, app.ChooseamplitudeSlider.Value*sin(t));
        end

        % Value changed function: ChoosefunctiontodrawDropDown
        function ChoosefunctiontodrawDropDownValueChanged(app, event)
            value = app.ChoosefunctiontodrawDropDown.Value;
            t= -4*pi: 0.1: 4*pi;
            switch value
                case 1
                    % Draw sin function
                    plot(app.UIAxes, app.ChooseamplitudeSlider.Value*sin(t));
                case 2
                    % Draw cos function
                    plot(app.UIAxes, app.ChooseamplitudeSlider.Value*cos(t));
                case 3
                    % Draw sin function
                    plot(app.UIAxes, app.ChooseamplitudeSlider.Value*tanh(t));
                case 4
                    % Draw sin function
                    plot(app.UIAxes, app.ChooseamplitudeSlider.Value*coth(t));
            end
        end

        % Value changed function: ChooseamplitudeSlider
        function ChooseamplitudeSliderValueChanged(app, event)
            value= app.ChooseamplitudeSlider.Value;
            app.Label.Text= sprintf('%2.2f', value);
            ChoosefunctiontodrawDropDownValueChanged(app, event);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trigonometric function graphic')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'x')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ChoosefunctiontodrawDropDownLabel = uilabel(app.UIFigure);
            app.ChoosefunctiontodrawDropDownLabel.Tag = 'dropdown1';
            app.ChoosefunctiontodrawDropDownLabel.Text = 'Choose function to draw';
            app.ChoosefunctiontodrawDropDown = uidropdown(app.UIFigure);
            app.ChoosefunctiontodrawDropDown.Items = {'Sin', 'Cos', 'Tanh', 'Coth'};
            app.ChoosefunctiontodrawDropDown.ItemsData = [1 2 3 4];
            app.ChoosefunctiontodrawDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosefunctiontodrawDropDownValueChanged, true);
            app.ChoosefunctiontodrawDropDown.Placeholder = 'Click here';
            app.ChoosefunctiontodrawDropDown.Value = 1;
            app.ChooseamplitudeSliderLabel = uilabel(app.UIFigure);
            app.ChooseamplitudeSliderLabel.Text = 'Choose amplitude';
            app.ChooseamplitudeSlider = uislider(app.UIFigure);
            app.ChooseamplitudeSlider.Limits = [1 5];
            app.ChooseamplitudeSlider.MajorTicks = [1 2 3 4 5];
            app.ChooseamplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @ChooseamplitudeSliderValueChanged, true);
            app.ChooseamplitudeSlider.Value = 1;
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A=';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trigonometric_function

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end