classdef uitable_xls_r_w < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        NewMenu                      matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        AboutMenu                    matlab.ui.container.Menu
        ResetButton                  matlab.ui.control.Button
        SavedatatoxlsxButton         matlab.ui.control.Button
        RocketparametersLabel        matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        SolveButton                  matlab.ui.control.Button
        ResultsPanel                 matlab.ui.container.Panel
        HeightcoastEditField         matlab.ui.control.EditField
        HeightcoastEditFieldLabel    matlab.ui.control.Label
        mLabel_4                     matlab.ui.control.Label
        mLabel_3                     matlab.ui.control.Label
        mLabel_2                     matlab.ui.control.Label
        TotalheightEditField         matlab.ui.control.EditField
        TotalheightEditFieldLabel    matlab.ui.control.Label
        HeightboastInputField        matlab.ui.control.EditField
        HeightboastInputFieldLabel   matlab.ui.control.Label
        WindresistantEditField       matlab.ui.control.EditField
        WindresistantEditFieldLabel  matlab.ui.control.Label
        secLabel_2                   matlab.ui.control.Label
        NLabel_2                     matlab.ui.control.Label
        m2Label                      matlab.ui.control.Label
        BurntimeEditField            matlab.ui.control.EditField
        BurntimeEditFieldLabel       matlab.ui.control.Label
        AreaInptutField              matlab.ui.control.EditField
        AreaInputFieldLabel          matlab.ui.control.Label
        UserinputPanel_2             matlab.ui.container.Panel
        Kgm3Label                    matlab.ui.control.Label
        mLabel                       matlab.ui.control.Label
        AirdensityEditField          matlab.ui.control.EditField
        AirdensityEditFieldLabel     matlab.ui.control.Label
        DiameterEditField            matlab.ui.control.EditField
        DiameterEditFieldLabel       matlab.ui.control.Label
        ReadfromDBPanel              matlab.ui.container.Panel
        MotorDropDown                matlab.ui.control.DropDown
        MotorDropDownLabel           matlab.ui.control.Label
        secLabel                     matlab.ui.control.Label
        NLabel                       matlab.ui.control.Label
        NsecLabel                    matlab.ui.control.Label
        TimedelayEditField           matlab.ui.control.EditField
        TimedelayEditFieldLabel      matlab.ui.control.Label
        ThrustEditField              matlab.ui.control.EditField
        ThrustEditFieldLabel         matlab.ui.control.Label
        TotalimpulseEditField        matlab.ui.control.EditField
        TotalimpulseEditFieldLabel   matlab.ui.control.Label
        UserinputPanel               matlab.ui.container.Panel
        Label_2                      matlab.ui.control.Label
        KgLabel                      matlab.ui.control.Label
        DragcoefEditField            matlab.ui.control.EditField
        DragcoefEditFieldLabel       matlab.ui.control.Label
        RocketmassEditField          matlab.ui.control.EditField
        RocketmassEditFieldLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: MotorDropDown
        function MotorDropDownOpening(app, event)
            MotorDropDownValueChanged(app, event)
        end

        % Value changed function: MotorDropDown
        function MotorDropDownValueChanged(app, event)

            % Read sheet 1 into data base
            filename= "engine_info.xlsx";
            dir= "C://Users/teacherforward/OneDrive/STEM_Course/GUI/";
            fulldir= strcat(dir, filename);
            data=readcell(fulldir); 
            % Set items for dropdown mennu
            items= data(:,1);
            set(app.MotorDropDown,'Items', items);
            value= app.MotorDropDown.Value;
            
            % I don't know how to get index of cell, so i do my algorithm
            id=0;
            for i=1: size(items,1)
                id=i;
                if items{i,1}==value
                    break
                end
            end
             % Once youve got id, let's update parameters
            app.TotalimpulseEditField.Value= num2str(data{id, 2});
            app.ThrustEditField.Value= num2str(data{id,3});
            app.TimedelayEditField.Value= num2str(data{id,4});


        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            
            % Grab all input params. Throw a prompt if there's not valid
            % inputed param. 
            I= str2double(app.TotalimpulseEditField.Value);
            T= str2double(app.ThrustEditField.Value);
            t_delay= str2double(app.TimedelayEditField.Value);
            M= str2double(app.RocketmassEditField.Value);
            ro= str2double(app.DragcoefEditField.Value);
            D= str2double(app.DiameterEditField.Value);
            Cd= str2double(app.AirdensityEditField.Value);
            g= 9.81;
         
            % Do the math.
            S= pi*D^2/4;
            k= 0.5*ro*Cd*S;
            t_burn= I/T;
            q= sqrt((T-M*g)/k);
            x= 2*k*q*M;
            v= q*(1- exp(-x*t_delay))/(1+exp(-x*t_delay));
            Hb= -M*log((T-M*g-k*v^2)/(T-M*g))/(2*k);
            Hc= M*log((M*g+k*v^2)/(M*g))/(2*k);
            totalH= Hb+Hc;

            % Update results
            app.AreaInptutField.Value= num2str(S);
            app.WindresistantEditField.Value= num2str(k);
            app.BurntimeEditField.Value= num2str(t_burn);
            app.HeightboastInputField.Value= num2str(Hb);
            app.HeightcoastEditField.Value= num2str(Hc);
            app.TotalheightEditField.Value= num2str(totalH);
            
           
            % Write results into the table
            % First create a global struct[count, Data] to save all results
            global df;
            df.count =df.count+1; 
            df.Data{df.count, 1}= sprintf('%d',df.count);
            df.Data{df.count, 2}=sprintf('%5.5f', t_burn);
            df.Data{df.count,3}=sprintf('%5.5f', Hb);
            df.Data{df.count, 4}=sprintf('%5.5f', Hc);
            df.Data{df.count,5}= sprintf('%5.5f', totalH);

            a= clock;
            date = sprintf('%d/%d/%d', a(3), a (2), a(1));
            date= strcat('Date: ', date);
            time= sprintf('%d:%d:%d', a(4), a(5), round(a(6)));
            time= strcat('Time: ', time);
            df.Data{df.count, 6}= date;
            df.Data{df.count, 7}= time;
       
            % Then refresh the table
            app.UITable.Data= df.Data;
            app.UITable.ColumnName= {'No' 't_burn' 'Hb' 'Hc' 'totalH', 'Date', 'Time'};

           
        end

        % Button pushed function: SavedatatoxlsxButton
        function SavedatatoxlsxButtonPushed(app, event)
            writecell({},'result_engine.xlsx');
           
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            % Write out result in the *.xlsx file 
            %[row col] = size(xlsread('C://Users/teacherforward/OneDrive/STEM_Course/GUI/result_engine.xlsx'));
            global df;
            for i=1:df.count
                xlswrite('result_engine.xlsx', df.Data(i, :), 1, ['A' num2str(i)]);
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           clear global;
           global df;
           df.count=0;
           app.UITable.ColumnName= {'No' 't_burn' 'Hb' 'Hc' 'totalH', 'Date', 'Time'};
           app.UITable.Data= {};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.Text = 'About';
            app.UserinputPanel = uipanel(app.UIFigure);
            app.UserinputPanel.Title = 'User input';
            app.RocketmassEditFieldLabel = uilabel(app.UserinputPanel);
            app.RocketmassEditFieldLabel.Text = 'Rocket mass';
            app.RocketmassEditField = uieditfield(app.UserinputPanel, 'text');
            app.DragcoefEditFieldLabel = uilabel(app.UserinputPanel);
            app.DragcoefEditFieldLabel.Text = 'Drag coef.';
            app.DragcoefEditField = uieditfield(app.UserinputPanel, 'text');
            app.KgLabel = uilabel(app.UserinputPanel);
            app.KgLabel.Text = 'Kg';
            app.Label_2 = uilabel(app.UserinputPanel);
            app.Label_2.Text = '~0.75';
            app.ReadfromDBPanel = uipanel(app.UIFigure);
            app.ReadfromDBPanel.Title = 'Read from DB';
            app.TotalimpulseEditFieldLabel = uilabel(app.ReadfromDBPanel);
            app.TotalimpulseEditFieldLabel.Text = 'Total impulse';
            app.TotalimpulseEditField = uieditfield(app.ReadfromDBPanel, 'text');
            app.ThrustEditFieldLabel = uilabel(app.ReadfromDBPanel);
            app.ThrustEditFieldLabel.Text = 'Thrust';
            app.ThrustEditField = uieditfield(app.ReadfromDBPanel, 'text');
            app.TimedelayEditFieldLabel = uilabel(app.ReadfromDBPanel);
            app.TimedelayEditFieldLabel.Text = 'Time delay';
            app.TimedelayEditField = uieditfield(app.ReadfromDBPanel, 'text');
            app.NsecLabel = uilabel(app.ReadfromDBPanel);
            app.NsecLabel.Text = 'N.sec';
            app.NLabel = uilabel(app.ReadfromDBPanel);
            app.NLabel.Text = 'N';
            app.secLabel = uilabel(app.ReadfromDBPanel);
            app.secLabel.Text = 'sec';
            app.MotorDropDownLabel = uilabel(app.ReadfromDBPanel);
            app.MotorDropDownLabel.Text = 'Motor';
            app.MotorDropDown = uidropdown(app.ReadfromDBPanel);
            app.MotorDropDown.Items = {};
            app.MotorDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MotorDropDownOpening, true);
            app.MotorDropDown.ValueChangedFcn = createCallbackFcn(app, @MotorDropDownValueChanged, true);
            app.MotorDropDown.Placeholder = 'Select ';
            app.MotorDropDown.Value = {};
            app.UserinputPanel_2 = uipanel(app.UIFigure);
            app.UserinputPanel_2.Title = 'User input';
            app.DiameterEditFieldLabel = uilabel(app.UserinputPanel_2);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.UserinputPanel_2, 'text');
            app.AirdensityEditFieldLabel = uilabel(app.UserinputPanel_2);
            app.AirdensityEditFieldLabel.Text = 'Air density';
            app.AirdensityEditField = uieditfield(app.UserinputPanel_2, 'text');
            app.mLabel = uilabel(app.UserinputPanel_2);
            app.mLabel.Text = 'm';
            app.Kgm3Label = uilabel(app.UserinputPanel_2);
            app.Kgm3Label.Text = 'Kg/m3';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.AreaInputFieldLabel = uilabel(app.ResultsPanel);
            app.AreaInputFieldLabel.Text = 'Area';
            app.AreaInptutField = uieditfield(app.ResultsPanel, 'text');
            app.BurntimeEditFieldLabel = uilabel(app.ResultsPanel);
            app.BurntimeEditFieldLabel.Text = 'Burn time';
            app.BurntimeEditField = uieditfield(app.ResultsPanel, 'text');
            app.m2Label = uilabel(app.ResultsPanel);
            app.m2Label.Text = 'm2';
            app.NLabel_2 = uilabel(app.ResultsPanel);
            app.NLabel_2.Text = 'N';
            app.secLabel_2 = uilabel(app.ResultsPanel);
            app.secLabel_2.Text = 'sec';
            app.WindresistantEditFieldLabel = uilabel(app.ResultsPanel);
            app.WindresistantEditFieldLabel.Text = 'Wind resistant ';
            app.WindresistantEditField = uieditfield(app.ResultsPanel, 'text');
            app.HeightboastInputFieldLabel = uilabel(app.ResultsPanel);
            app.HeightboastInputFieldLabel.Text = 'Height boost';
            app.HeightboastInputField = uieditfield(app.ResultsPanel, 'text');
            app.TotalheightEditFieldLabel = uilabel(app.ResultsPanel);
            app.TotalheightEditFieldLabel.Text = 'Total height';
            app.TotalheightEditField = uieditfield(app.ResultsPanel, 'text');
            app.mLabel_2 = uilabel(app.ResultsPanel);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.ResultsPanel);
            app.mLabel_3.Text = 'm';
            app.mLabel_4 = uilabel(app.ResultsPanel);
            app.mLabel_4.Text = 'm';
            app.HeightcoastEditFieldLabel = uilabel(app.ResultsPanel);
            app.HeightcoastEditFieldLabel.Text = 'Height coast';
            app.HeightcoastEditField = uieditfield(app.ResultsPanel, 'text');
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'s'};
            app.UITable.RowName = {};
            app.RocketparametersLabel = uilabel(app.UIFigure);
            app.RocketparametersLabel.Text = 'Rocket parameters';
            app.SavedatatoxlsxButton = uibutton(app.UIFigure, 'push');
            app.SavedatatoxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @SavedatatoxlsxButtonPushed, true);
            app.SavedatatoxlsxButton.Text = 'Save data to *.xlsx';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uitable_xls_r_w

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end